classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SlikasaumomLabel              matlab.ui.control.Label
        NoisyImage                    matlab.ui.control.Image
        OriginalnaslikaLabel          matlab.ui.control.Label
        PrimeniumButton               matlab.ui.control.Button
        OdaberitejainuumaSlider       matlab.ui.control.Slider
        OdaberitejainuumaSliderLabel  matlab.ui.control.Label
        Original                      matlab.ui.control.Image
    end

    
     properties (Access = private)
        LoadedImage % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: OdaberitejainuumaSlider
        function OdaberitejainuumaSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: PrimeniumButton
        function PrimeniumButtonPushed(app, event)
            % Učitaj originalnu sliku
            originalImage = app.LoadedImage;  % Pretpostavljamo da slika već postoji u aplikaciji
           % Dobij vrednost jačine šuma sa slajdera
            noiseLevel = app.OdaberitejainuumaSlider.Value;  % Vrednost sa slajdera
            noiseLevel = noiseLevel / 10;
            % Dodaj Salt & Pepper šum na sliku
            noisyImage = imnoise(originalImage, 'salt & pepper', noiseLevel);
            % Prikazivanje šumovite slike u aplikaciji
            app.NoisyImage.ImageSource = noisyImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.OdaberitejainuumaSliderLabel = uilabel(app.UIFigure);
            app.OdaberitejainuumaSliderLabel.Text = 'Odaberite jačinu šuma';
            app.OdaberitejainuumaSlider = uislider(app.UIFigure);
            app.OdaberitejainuumaSlider.Limits = [0 10];
            app.OdaberitejainuumaSlider.ValueChangingFcn = createCallbackFcn(app, @OdaberitejainuumaSliderValueChanging, true);
            app.PrimeniumButton = uibutton(app.UIFigure, 'push');
            app.PrimeniumButton.ButtonPushedFcn = createCallbackFcn(app, @PrimeniumButtonPushed, true);
            app.PrimeniumButton.Text = 'Primeni šum';
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.NoisyImage = uiimage(app.UIFigure);
            app.SlikasaumomLabel = uilabel(app.UIFigure);
            app.SlikasaumomLabel.Text = 'Slika sa šumom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end